classdef WheelArch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CarPanel                        matlab.ui.container.Panel
        WheelbasemmEditFieldLabel       matlab.ui.control.Label
        WheelbasemmEditField            matlab.ui.control.NumericEditField
        TrackmmEditFieldLabel           matlab.ui.control.Label
        TrackmmEditField                matlab.ui.control.NumericEditField
        TireDiamtermmEditFieldLabel     matlab.ui.control.Label
        TireDiamtermmEditField          matlab.ui.control.NumericEditField
        TireWidthmmEditFieldLabel       matlab.ui.control.Label
        TireWidthmmEditField            matlab.ui.control.NumericEditField
        SteerRadiusmmEditFieldLabel     matlab.ui.control.Label
        SteerRadiusmmEditField          matlab.ui.control.NumericEditField
        VehicleMasskgEditFieldLabel     matlab.ui.control.Label
        VehicleMasskgEditField          matlab.ui.control.NumericEditField
        CGmmEditFieldLabel              matlab.ui.control.Label
        CGmmEditField                   matlab.ui.control.NumericEditField
        FRmmEditFieldLabel              matlab.ui.control.Label
        FRmmEditField                   matlab.ui.control.NumericEditField
        RRmmEditFieldLabel              matlab.ui.control.Label
        RRmmEditField                   matlab.ui.control.NumericEditField
        LUGGAGEmmEditFieldLabel         matlab.ui.control.Label
        LUGGAGEmmEditField              matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        FrontRowEditFieldLabel          matlab.ui.control.Label
        FrontRowEditField               matlab.ui.control.NumericEditField
        RearRowEditFieldLabel           matlab.ui.control.Label
        RearRowEditField                matlab.ui.control.NumericEditField
        NumberofPassengersLabel         matlab.ui.control.Label
        EmptyLoadGroundClearanceLabel   matlab.ui.control.Label
        FrontmmLabel                    matlab.ui.control.Label
        GCFrontEditField                matlab.ui.control.NumericEditField
        RearmmLabel                     matlab.ui.control.Label
        GCRearEditField                 matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        TopViewTab                      matlab.ui.container.Tab
        TopView                         matlab.ui.control.UIAxes
        delta11                         matlab.ui.control.Label
        delta12                         matlab.ui.control.Label
        delta11v                        matlab.ui.control.Label
        delta12v                        matlab.ui.control.Label
        SideViewTab                     matlab.ui.container.Tab
        SideView                        matlab.ui.control.UIAxes
        ScaleSliderLabel                matlab.ui.control.Label
        ScaleSlider                     matlab.ui.control.Slider
        ResultsTab                      matlab.ui.container.Tab
        ResultsTable                    matlab.ui.control.Table
        FrontSuspensionTab              matlab.ui.container.Tab
        FrontSusp                       matlab.ui.control.UIAxes
        Kf                              matlab.ui.control.Label
        Kfv                             matlab.ui.control.Label
        RearSuspensionTab               matlab.ui.container.Tab
        RearSusp                        matlab.ui.control.UIAxes
        Krv                             matlab.ui.control.Label
        Krf                             matlab.ui.control.Label
        BodePlotTab                     matlab.ui.container.Tab
        BMag                            matlab.ui.control.UIAxes
        PSDTab                          matlab.ui.container.Tab
        PSD                             matlab.ui.control.UIAxes
        MSVTab                          matlab.ui.container.Tab
        MSV                             matlab.ui.control.UIAxes
        OptimizeButton                  matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        OptimalDampingCoefficientLabel  matlab.ui.control.Label
        ODC                             matlab.ui.control.Label
        ClearAllButton                  matlab.ui.control.Button
        RoadSuspensionPanel             matlab.ui.container.Panel
        BumpStopsLabel                  matlab.ui.control.Label
        MaxCompressionmmEditFieldLabel  matlab.ui.control.Label
        MaxCompressionmmEditField       matlab.ui.control.NumericEditField
        MaxExpansionmmEditFieldLabel    matlab.ui.control.Label
        MaxExpansionmmEditField         matlab.ui.control.NumericEditField
        LowerLimitmmEditFieldLabel      matlab.ui.control.Label
        LowerLimitmmEditField           matlab.ui.control.NumericEditField
        UpperLimitmmEditFieldLabel      matlab.ui.control.Label
        UpperLimitmmEditField           matlab.ui.control.NumericEditField
        NaturalFrequencyHzSliderLabel   matlab.ui.control.Label
        NaturalFrequencyHzSlider        matlab.ui.control.Slider
        RoadGradeLabel                  matlab.ui.control.Label
        RoadGrade                       matlab.ui.control.DropDown
        VelocitykmhEditFieldLabel       matlab.ui.control.Label
        VelocityEditField               matlab.ui.control.NumericEditField
        ClearPlotsButton                matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [p_r, p_f, Ff, Fr] = getDist(~, n_front, n_rear, m_occupant, m_luggage, mass_min,...
                FR, CG, RR, LUGGAGE, wheelbase)
            Fr = (n_front*m_occupant*FR + mass_min*CG + n_rear*m_occupant*RR + (n_front+n_rear)*m_luggage*LUGGAGE)*9.81/wheelbase;
            Ff = ((n_front + n_rear)*(m_occupant + m_luggage) + mass_min)*9.81 - Fr;
            p_f = Ff/(Ff + Fr);
            p_r = Fr/(Ff + Fr);
        end
        
        function [x1, y1, x2, y2] = circle(~, a, b, r, c, d, R)
            th = 0:pi/50:2*pi;
            x1 = r * cos(th) + a;
            y1 = r * sin(th) + b;
            x2 = R * cos(th) + c;
            y2 = R * sin(th) + d;
        end
        
        function [x1, y1, x2, y2] = semicircle(app, a, b, r, c, d, R)
            th = 0:pi/50:pi;
            x1 = r * cos(th) + a;
            y1 = r * sin(th) + b;
            x2 = R * cos(th) + c;
            y2 = R * sin(th) + d;
        end
        
        function G = StateSpace(app, Ms, Ks, Cs)
            Mu = .1*Ms; % [kg]
            Kt = 10*Ks; % [N/m]
            if(Cs == 0)
                Cs = sqrt(Ks*Ms)/2; % [Ns/m]
            end
            M = [Ms 0; 0 Mu];
            K = [Ks -Ks; -Ks Ks+Kt];
            Cc = [Cs -Cs; -Cs Cs];
            In = [0; Kt];
            A = [zeros(2, 2) eye(2);-M\K -M\Cc];
            B = [zeros(2, 1); M\In];
            To = [1 0];
            C = [-To*(M\K) -To*(M\Cc)];
            D = To*(M\In);
            G = ss(A, B, C, D);
        end
        
        
        function mag = bodeGUI(app, sys, f)
            w = 2*pi*f; % [rad/s]
            mag = freqresp(sys, w);
            [Ny, Nu, lw] = size(mag);
            mag = reshape(mag, [Ny*Nu, lw]).';
            mag = abs(mag);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function onCalculateClicked(app, event)
            app.OptimizeButton.Enable = false;
            % Get Data From UI and Check For Validity
            wheelbase = app.WheelbasemmEditField.Value;
            if wheelbase <= 0
                fprintf('Invalid Wheelbase!\n')
                return
            end
            track = app.TrackmmEditField.Value;
            if track <= 0
                fprintf('Invalid Track!\n')
                return
            end
            tire_diameter = app.TireDiamtermmEditField.Value;
            if tire_diameter <= 0
                fprintf('Invalid Tire Diameter!\n')
                return
            end
            tire_width = app.TireWidthmmEditField.Value;
            if tire_width <= 0
                fprintf('Invalid Tire Width!\n')
                return
            end
            min_steer_r = app.SteerRadiusmmEditField.Value;
            if min_steer_r <= 0
                fprintf('Invalid Steer Radius!\n')
                return
            end
            mass_min = app.VehicleMasskgEditField.Value;
            if mass_min <= 0
                fprintf('Invalid Vehicle Mass!\n')
                return
            end
            n_front = app.FrontRowEditField.Value;
            if n_front <= 0
                fprintf('Invalid Number of Front Row Passengers!\n')
                return
            end
            n_rear = app.RearRowEditField.Value;
            if n_rear < 0
                fprintf('Invalid Number of Front Row Passengers!\n')
                return
            end
            n_tot = n_front + n_rear;
            CG = app.CGmmEditField.Value;
            FR = app.FRmmEditField.Value;
            RR = app.RRmmEditField.Value;
            LUGGAGE = app.LUGGAGEmmEditField.Value;
            if CG <= 0 || FR <= 0 || RR < 0 || LUGGAGE == 0
                fprintf('Invalid Measurement!\n')
                return
            end
            GC_f = app.GCFrontEditField.Value;
            GC_r = app.GCRearEditField.Value;
            if GC_f <= 0 || GC_r <= 0
                fprintf('Invalid Ground Clearance!\n')
            end
            m_comp = app.MaxCompressionmmEditField.Value;
            m_exp = app.MaxExpansionmmEditField.Value;
            upper_limit = app.UpperLimitmmEditField.Value;
            lower_limit = app.LowerLimitmmEditField.Value;
            if m_comp <= 0 || m_exp <= 0 || upper_limit <= 0 ...
                    || lower_limit <= 0 || m_comp < upper_limit ...
                    || m_exp < lower_limit
                fprintf('Invalid Road and/or Suspension Profile Choice!\n')
                return
            end
            Velocity = app.VelocityEditField.Value;
            if Velocity <= 0
                fprintf('Invalid Velocity!\n')
                return
            end
            scale = app.ScaleSlider.Value*1e3;
            tire_diameter = tire_diameter + 2*20;
            tire_diameterF = tire_diameter;
            tire_diameterR = tire_diameter;
            if app.DropDown.Value == '0'
                tire_diameterF = tire_diameter + 2*15;
            elseif app.DropDown.Value == '1'
                tire_diameterR = tire_diameter + 2*15;
            else
                tire_diameterF = tire_diameter + 2*15;
                tire_diameterR = tire_diameter + 2*15;
            end
            
            % Steering Kinematics
            R1 = sqrt(min_steer_r^2 - wheelbase^2) - track/2;
            delta_11 = atand(wheelbase/(R1-track/2));
            delta_12 = atand(wheelbase/(R1+track/2));
            A = [-tire_diameterF/2; tire_width/2];
            B = [-tire_diameterF/2; -tire_width/2];
            C = [tire_diameterF/2; -tire_width/2];
            D = [tire_diameterF/2; tire_width/2];
            E = [-tire_diameterR/2+wheelbase; tire_width/2];
            F = [-tire_diameterR/2+wheelbase; -tire_width/2];
            G = [tire_diameterR/2+wheelbase; -tire_width/2];
            H = [tire_diameterR/2+wheelbase; tire_width/2];
            Rear = [E(1) F(1) G(1) H(1) E(1); E(2) F(2) G(2) H(2) E(2)];
            center = [0; tire_width/4];
            Arot = A-center;
            Brot = B-center;
            Crot = C-center;
            Drot = D-center;
            
            Rot1 = [cosd(delta_11) -sind(delta_11); sind(delta_11) cosd(delta_11)];
            A1 = Rot1*Arot + center;
            B1 = Rot1*Brot + center;
            C1 = Rot1*Crot + center;
            D1 = Rot1*Drot + center;
            Rot2 = [cosd(-delta_12) -sind(-delta_12); sind(-delta_12) cosd(-delta_12)];
            A2 = Rot2*Arot + center;
            B2 = Rot2*Brot + center;
            C2 = Rot2*Crot + center;
            D2 = Rot2*Drot + center;
            initial = [A(1) B(1) C(1) D(1) A(1); A(2) B(2) C(2) D(2) A(2)];
            delta1 = [A1(1) B1(1) C1(1) D1(1) A1(1); A1(2) B1(2) C1(2) D1(2) A1(2)];
            delta2 = [A2(1) B2(1) C2(1) D2(1) A2(1); A2(2) B2(2) C2(2) D2(2) A2(2)];
            
            cla(app.TopView)
            plot(app.TopView, initial(1, :), initial(2, :), 'r',...
                Rear(1, :), Rear(2, :), 'r',...
                delta1(1, :), delta1(2, :), 'g', ...
                delta2(1, :), delta2(2, :), 'b', ...
                center(1), center(2), '*k')
            hold(app.TopView, 'on')
            
            % Draw Top
            FF = [-tire_diameterF/2-(m_comp+100); -tire_width/2];
            FBO = [-tire_diameterF/2-m_comp; -tire_width/2];
            FBI = [-tire_diameterF/2-m_comp; tire_width/2];
            FI1 = [A2(1); D1(2)+50];
            FI2 = [D1(1); D1(2)+50];
            MFI = [tire_diameterF/2+m_comp; tire_width/2];
            MFO = [tire_diameterF/2+m_comp; -tire_width/2];
            MBO = [-tire_diameterR/2-m_comp; -tire_width/2] + [wheelbase; 0];
            MBI = [-tire_diameterR/2-m_comp; tire_width/2+50] + [wheelbase; 0];
            BFI = [tire_diameterR/2+m_comp; tire_width/2+50] + [wheelbase; 0];
            BFO = [tire_diameterR/2+m_comp; -tire_width/2] + [wheelbase; 0];
            BB = [tire_diameterR/2+(m_comp+100); -tire_width/2] + [wheelbase; 0];
            TP = [FF, FBO, FBI, FI1, FI2, MFI, MFO, MBO, MBI, BFI, BFO, BB];
            plot(app.TopView, TP(1, :), TP(2, :), 'k', 'LineWidth', 2)
            app.TopView.XLim = [FF(1)-50, BB(1)+50];
            axis(app.TopView, 'equal')
            
            app.delta11v.Text = [num2str(floor(delta_11)) ' [deg]'];
            app.delta12v.Text = [num2str(floor(delta_12)) ' [deg]'];
            
            f = app.NaturalFrequencyHzSlider.Value; % [Hz]
            m_occupant = 70; % [kg]
            m_luggage = 10; % [kg]
            mass_max = mass_min + n_tot*m_occupant + n_tot*m_luggage; % [kg]
            
            % Distribution
            [p_r, p_f, ~, ~] = getDist(app, n_front, n_rear, m_occupant, m_luggage, mass_min,...
                FR, CG, RR, LUGGAGE, wheelbase);
            
            
            % Sprung Mass
            Ms_f = p_f*mass_max;
            Ms_r = p_r*mass_max;
            
            % Spring Stiffness
            Ks_f = (f*2*pi)^2*Ms_f; % [N/m]
            Ks_r = (f*2*pi)^2*Ms_r; % [N/m]
            
            deltaF_f = zeros(n_tot+1, 1);
            deltaF_r = zeros(n_tot+1, 1);
            deltaZ_f = zeros(n_tot+1, 1);
            deltaZ_r = zeros(n_tot+1, 1);
            FGC = [GC_f; zeros(n_tot, 1)];
            RGC = [GC_r; zeros(n_tot, 1)];
            
            % Case A - Empty
            [~, pf_A, Ff_A, Fr_A] = getDist(app, 0, 0, m_occupant, m_luggage, mass_min,...
                FR, CG, RR, LUGGAGE, wheelbase);
            
            % All Other Cases
            colors = {'k', 'b', 'r', 'g', 'c', 'm'};
            cla(app.SideView)
            for i = 1:(n_tot + 1)
                n_f = min([i-(n_front-1), n_front]);
                n_r = max([i-3, 0]);
                [~, ~, Ff, Fr] = getDist(app, n_f, n_r, m_occupant, m_luggage, mass_min,...
                    FR, CG, RR, LUGGAGE, wheelbase);
                deltaF_f(i) = (Ff - Ff_A);
                deltaF_r(i) = (Fr - Fr_A);
                deltaZ_f(i) = deltaF_f(i)/Ks_f;
                deltaZ_r(i) = deltaF_r(i)/Ks_r;
                FGC(i) = FGC(1) - deltaZ_f(i)*1e3;
                RGC(i) = RGC(1) - deltaZ_r(i)*1e3;
                [x1, y1, x2, y2] = circle(app, 0, scale*deltaZ_f(i)+tire_diameterF/2, tire_diameterF/2, wheelbase, ...
                    scale*deltaZ_r(i)+tire_diameterR/2, tire_diameterR/2);
                plot(app.SideView, x1, y1, char(colors(i)), x2, y2, char(colors(i)), [0; wheelbase], [deltaZ_f(i);...
                    deltaZ_r(i)]*scale, ['--' char(colors(i))], ...
                    0, deltaZ_f(i)*scale+tire_diameterF/2, ['*' char(colors(i))], ...
                    wheelbase, deltaZ_r(i)*scale+tire_diameterR/2, ['*' char(colors(i))])
                hold(app.SideView, 'on')
            end
            app.SideView.XLim = [-ceil(tire_diameterF/2/1e2)*1e2, ceil((wheelbase+tire_diameterR/2)/1e3)*1e3];
            axis(app.SideView, 'equal')
            xlabel(app.SideView, 'Length [mm]')
            ylabel(app.SideView, 'Height [mm]')
            if scale ~= 1e3
                max_front = tire_diameterF/2 + 1e3*deltaZ_f(end);
                max_rear = tire_diameterR/2 + 1e3*deltaZ_r(end);
                txt_f = ['Distance = ' num2str(ceil(max_front))];
                txt_r = ['Distance = ' num2str(ceil(max_rear))];
                text(app.SideView, 0, tire_diameterF/2 + scale*deltaZ_f(end)+100, txt_f, 'Color', 'm')
                text(app.SideView, wheelbase, tire_diameterR/2 + scale*deltaZ_r(end)+100, txt_r, 'Color', 'm')
            else
                % Draw Side View
                FF = [-tire_diameterF/2-(m_comp+100); GC_f];
                FBL = [-tire_diameterF/2-m_comp; GC_f];
                FBU = [-tire_diameterF/2-m_comp; tire_diameterF/2];
                MFU = [tire_diameterF/2+m_comp; tire_diameterF/2];
                MFL = [tire_diameterF/2+m_comp; GC_f];
                MBL = [-tire_diameterR/2-m_comp; GC_r] + [wheelbase; 0];
                MBU = [-tire_diameterR/2-m_comp; tire_diameterR/2] + [wheelbase; 0];
                BFU = [tire_diameterR/2+m_comp; tire_diameterR/2] + [wheelbase; 0];
                BFL = [tire_diameterR/2+m_comp; GC_r] + [wheelbase; 0];
                BB = [tire_diameterR/2+(m_comp+100); GC_r] + [wheelbase; 0];
                SV1 = [FF, FBL, FBU];
                SV2 = [MFU, MFL, MBL, MBU];
                SV3 = [BFU, BFL, BB];
                [x1, y1, x2, y2] = semicircle(app, 0, tire_diameterF/2, tire_diameterF/2+m_comp, wheelbase, tire_diameterR/2, tire_diameterR/2+m_comp);
                plot(app.SideView, SV1(1, :), SV1(2, :), 'k', 'LineWidth', 2)
                plot(app.SideView, SV2(1, :), SV2(2, :), 'k', 'LineWidth', 2)
                plot(app.SideView, SV3(1, :), SV3(2, :), 'k', 'LineWidth', 2)
                plot(app.SideView, x1, y1, 'k', 'LineWidth', 2)
                plot(app.SideView, x2, y2, 'k', 'LineWidth', 2)
                app.SideView.XLim = [FF(1)-50, BB(1)+50];
                axis(app.SideView, 'equal')
            end
            
            app.Kfv.Text = [num2str(floor(Ks_f)) ' [N/m]'];
            app.Krv.Text = [num2str(floor(Ks_r)) ' [N/m]'];
            
            Load_Case = {'A', 'B', 'C', 'D', 'E', 'F'}';
            deltaZ_f = deltaZ_f*1e3;
            deltaZ_r = deltaZ_r*1e3;
            Results = table(Load_Case(1:n_tot+1), deltaF_f, deltaF_r, deltaZ_f, deltaZ_r, FGC, RGC);
            app.ResultsTable.Data = Results;
            
            % Plot Spring Characteristics
            zitta = linspace(-m_exp, m_comp, 1000);
            lower_limit = -lower_limit;
            Fs_f = zeros(1000, 1);
            Kb_f = Ks_f*1e-3/(m_comp - upper_limit);
            Kb_f2 = Ks_f*1e-3/(lower_limit + m_exp);
            Fs_fl = Ks_f*1e-3*zitta + Ff_A;
            Fs_f(zitta>lower_limit & zitta<upper_limit) = Fs_fl(zitta>lower_limit & zitta<upper_limit);
            Fs_f(zitta<=lower_limit) = -Kb_f2*(lower_limit - zitta(zitta<=lower_limit)).^2 + Fs_fl(zitta<=lower_limit);
            Fs_f(zitta>=upper_limit) = Kb_f*(upper_limit - zitta(zitta>=upper_limit)).^2 + Fs_fl(zitta>=upper_limit);
            cla(app.FrontSusp)
            plot(app.FrontSusp, zitta, Fs_f*1e-3, 'r', 'LineWidth', 1.5)
            app.FrontSusp.XLim = [-m_exp-10, m_comp+10];
            
            Fs_r = zeros(1000, 1);
            Kb_r = Ks_r*1e-3/(m_comp - upper_limit);
            Kb_r2 = Ks_r*1e-3/(lower_limit + m_exp);
            Fs_rl = Ks_r*1e-3*zitta + Fr_A;
            Fs_r(zitta>lower_limit & zitta<upper_limit) = Fs_rl(zitta>lower_limit & zitta<upper_limit);
            Fs_r(zitta<=lower_limit) = -Kb_r2*(lower_limit - zitta(zitta<=lower_limit)).^2 + Fs_rl(zitta<=lower_limit);
            Fs_r(zitta>=upper_limit) = Kb_r*(upper_limit - zitta(zitta>=upper_limit)).^2 + Fs_rl(zitta>=upper_limit);
            cla(app.RearSusp)
            plot(app.RearSusp, zitta, Fs_r*1e-3, 'r', 'LineWidth', 1.5)
            app.RearSusp.XLim = [-m_exp-10, m_comp+10];
            
            % Frequency Response
            sys = StateSpace(app, Ms_f, Ks_f, 0);
            freq = (0.01:0.1:80)';
            mag = bodeGUI(app, sys, freq);
            cla(app.BMag)
            loglog(app.BMag, freq, mag);
            hold(app.BMag, 'on')
            
            % Human Sensitivity
            num = [80.03 989 0.02108];
            den = [1 78.92 2412 5614];
            H_2631 = tf(num, den);
            G = tf(sys);
            GH = G*H_2631;
            GH_mag = bodeGUI(app, GH, freq);
            loglog(app.BMag, freq, GH_mag);
            legend(app.BMag, {'Sprung Mass Acc. [m/s^2]', 'ISO Weighted Acc. [m/s^2]'}, 'Location', 'SE')
            
            % Power Spectral Density
            if app.RoadGrade.Value == '0'
                c = 1.6*1e-7;
            elseif app.RoadGrade.Value == '1'
                c = 6.4*1e-7;
            elseif app.RoadGrade.Value == '2'
                c = 2.56*1e-6;
            elseif app.RoadGrade.Value == '3'
                c = 1.024*1e-5;
            elseif app.RoadGrade.Value == '4'
                c = 4.096*1e-5;
            elseif app.RoadGrade.Value == '5'
                c = 1.6384*1e-4;
            elseif app.RoadGrade.Value == '6'
                c = 6.5536*1e-4;
            elseif app.RoadGrade.Value == '7'
                c = 2.62144*1e-3;
            end
            vel = Velocity/3.6; % [m/s]
            Sin = c*vel.*freq.^(-2);
            Sout = mag.^2.*Sin;
            Socc = GH_mag.^2.*Sin;
            cla(app.PSD)
            loglog(app.PSD, freq, Sin)
            hold(app.PSD, 'on')
            loglog(app.PSD, freq, Sout)
            loglog(app.PSD, freq, Socc)
            legend(app.PSD, {'Input Profile [m^2/Hz]', 'Sprung Mass Acc. [m^2/s^4/Hz]', 'ISO Weighted Acc. [m^2/s^4/Hz]'}, 'Location', 'SE')
            
            % Save Relevant Variables 
            Ms = pf_A*mass_min;
            Ks = Ks_f;
            setappdata(app.MSV, 'Ms', Ms)
            setappdata(app.MSV, 'Ks', Ks)
            setappdata(app.MSV, 'freq', freq)
            setappdata(app.MSV, 'H_2631', H_2631)
            setappdata(app.MSV, 'Sin', Sin)
            app.OptimizeButton.Enable = true;
            app.Lamp.Color = 'g';
        end

        % Button pushed function: ClearAllButton
        function onResetClicked(app, event)
            app.WheelbasemmEditField.Value = 0;
            app.TrackmmEditField.Value = 0;
            app.TireDiamtermmEditField.Value = 0;
            app.TireWidthmmEditField.Value = 0;
            app.SteerRadiusmmEditField.Value = 0;
            app.VehicleMasskgEditField.Value = 0;
            app.CGmmEditField.Value = 0;
            app.FRmmEditField.Value = 0;
            app.RRmmEditField.Value = 0;
            app.LUGGAGEmmEditField.Value = 0;
            app.FrontRowEditField.Value = 0;
            app.RearRowEditField.Value = 0;
            app.GCFrontEditField.Value = 0;
            app.GCRearEditField.Value = 0;
            app.MaxCompressionmmEditField.Value = 0;
            app.MaxExpansionmmEditField.Value = 0;
            app.UpperLimitmmEditField.Value = 0;
            app.LowerLimitmmEditField.Value = 0;
            app.VelocityEditField.Value = 0;
            cla(app.TopView)
            app.delta11v.Text = '0 [deg]';
            app.delta12v.Text = '0 [deg]';
            cla(app.SideView)
            cla(app.FrontSusp)
            app.Kfv.Text = '0 [N/m]';
            cla(app.RearSusp)
            app.Krv.Text = '0 [N/m]';
            app.ResultsTable.Data = [];
            cla(app.BMag)
            cla(app.PSD)
            cla(app.MSV)
            app.OptimizeButton.Enable = false;
            app.Lamp.Color = [0.5, 0.5, 0.5];
            app.ODC.Text = '0';
        end

        % Button pushed function: ClearPlotsButton
        function ClearPlots(app, event)
            cla(app.TopView)
            app.delta11v.Text = '0 [deg]';
            app.delta12v.Text = '0 [deg]';
            cla(app.SideView)
            cla(app.FrontSusp)
            app.Kfv.Text = '0 [N/m]';
            cla(app.RearSusp)
            app.Krv.Text = '0 [N/m]';
            cla(app.BMag)
            cla(app.PSD)
            cla(app.MSV)
            app.OptimizeButton.Enable = false;
            app.Lamp.Color = [0.5, 0.5, 0.5];
            app.ODC.Text = '0';
        end

        % Button pushed function: OptimizeButton
        function Optimize(app, event)
            app.Lamp.Color = 'r';
            pause(1)
            Ms = getappdata(app.MSV, 'Ms');
            Ks = getappdata(app.MSV, 'Ks');
            freq = getappdata(app.MSV, 'freq');
            H_2631 = getappdata(app.MSV, 'H_2631');
            Sin = getappdata(app.MSV, 'Sin');
            Cs = 1e2:10:1e4;
            for i = 1:length(Cs)
                G = StateSpace(app, Ms, Ks, Cs(i));
                G = prescale(G, {min(freq), max(freq)});
                mag = bodeGUI(app, G, freq);
                GH = G*H_2631;
                GH = prescale(GH, {min(freq), max(freq)});
                GH_mag = bodeGUI(app, GH, freq);
                Sout = mag.^2.*Sin;
                Socc = GH_mag.^2.*Sin;
                MSV_qs(i) = trapz(freq, Sout);
                MSV_qocc(i) = trapz(freq, Socc);
            end
            cla(app.MSV)
            loglog(app.MSV, Cs, MSV_qs)
            hold(app.MSV, 'on')
            loglog(app.MSV, Cs, MSV_qocc)
            legend(app.MSV, {'From Sprung Mass Acc.', 'From ISO Weighted Accel.'})
            app.Lamp.Color = 'g';
            cs_min = Cs(MSV_qocc == min(MSV_qocc));
            app.ODC.Text = [num2str(cs_min) ' [Ns/m]'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CarPanel = uipanel(app.UIFigure);
            app.CarPanel.Title = 'Car';
            app.WheelbasemmEditFieldLabel = uilabel(app.CarPanel);
            app.WheelbasemmEditFieldLabel.Text = 'Wheelbase [mm]';
            app.WheelbasemmEditField = uieditfield(app.CarPanel, 'numeric');
            app.TrackmmEditFieldLabel = uilabel(app.CarPanel);
            app.TrackmmEditFieldLabel.Text = 'Track [mm]';
            app.TrackmmEditField = uieditfield(app.CarPanel, 'numeric');
            app.TireDiamtermmEditFieldLabel = uilabel(app.CarPanel);
            app.TireDiamtermmEditFieldLabel.Text = 'Tire Diamter [mm]';
            app.TireDiamtermmEditField = uieditfield(app.CarPanel, 'numeric');
            app.TireWidthmmEditFieldLabel = uilabel(app.CarPanel);
            app.TireWidthmmEditFieldLabel.Text = 'Tire Width [mm]';
            app.TireWidthmmEditField = uieditfield(app.CarPanel, 'numeric');
            app.SteerRadiusmmEditFieldLabel = uilabel(app.CarPanel);
            app.SteerRadiusmmEditFieldLabel.Text = 'Steer Radius [mm]';
            app.SteerRadiusmmEditField = uieditfield(app.CarPanel, 'numeric');
            app.VehicleMasskgEditFieldLabel = uilabel(app.CarPanel);
            app.VehicleMasskgEditFieldLabel.Text = 'Vehicle Mass [kg]';
            app.VehicleMasskgEditField = uieditfield(app.CarPanel, 'numeric');
            app.CGmmEditFieldLabel = uilabel(app.CarPanel);
            app.CGmmEditFieldLabel.Text = 'CG [mm]';
            app.CGmmEditField = uieditfield(app.CarPanel, 'numeric');
            app.FRmmEditFieldLabel = uilabel(app.CarPanel);
            app.FRmmEditFieldLabel.Text = 'FR [mm]';
            app.FRmmEditField = uieditfield(app.CarPanel, 'numeric');
            app.RRmmEditFieldLabel = uilabel(app.CarPanel);
            app.RRmmEditFieldLabel.Text = 'RR [mm]';
            app.RRmmEditField = uieditfield(app.CarPanel, 'numeric');
            app.LUGGAGEmmEditFieldLabel = uilabel(app.CarPanel);
            app.LUGGAGEmmEditFieldLabel.Text = 'LUGGAGE [mm]';
            app.LUGGAGEmmEditField = uieditfield(app.CarPanel, 'numeric');
            app.DropDown = uidropdown(app.CarPanel);
            app.DropDown.Items = {'Front Wheel Drive', 'Rear Wheel Drive', 'All Wheel Drive'};
            app.DropDown.ItemsData = {'0', '1', '2'};
            app.DropDown.Value = '0';
            app.FrontRowEditFieldLabel = uilabel(app.CarPanel);
            app.FrontRowEditFieldLabel.Text = 'Front Row';
            app.FrontRowEditField = uieditfield(app.CarPanel, 'numeric');
            app.RearRowEditFieldLabel = uilabel(app.CarPanel);
            app.RearRowEditFieldLabel.Text = 'Rear Row';
            app.RearRowEditField = uieditfield(app.CarPanel, 'numeric');
            app.NumberofPassengersLabel = uilabel(app.CarPanel);
            app.NumberofPassengersLabel.Text = 'Number of Passengers';
            app.EmptyLoadGroundClearanceLabel = uilabel(app.CarPanel);
            app.EmptyLoadGroundClearanceLabel.Text = 'Empty Load Ground Clearance';
            app.FrontmmLabel = uilabel(app.CarPanel);
            app.FrontmmLabel.Text = 'Front [mm]';
            app.GCFrontEditField = uieditfield(app.CarPanel, 'numeric');
            app.RearmmLabel = uilabel(app.CarPanel);
            app.RearmmLabel.Text = 'Rear [mm]';
            app.GCRearEditField = uieditfield(app.CarPanel, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @onCalculateClicked, true);
            app.CalculateButton.Text = 'Calculate';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.Image = uiimage(app.DataTab);
            app.Image.ImageSource = 'Dimensions.png';
            app.TopViewTab = uitab(app.TabGroup);
            app.TopViewTab.Title = 'Top View';
            app.TopView = uiaxes(app.TopViewTab);
            title(app.TopView, 'Top View')
            xlabel(app.TopView, 'X')
            ylabel(app.TopView, 'Y')
            app.delta11 = uilabel(app.TopViewTab);
            app.delta11.Text = 'δ11 = ';
            app.delta12 = uilabel(app.TopViewTab);
            app.delta12.Text = 'δ12 = ';
            app.delta11v = uilabel(app.TopViewTab);
            app.delta11v.Text = '0 [deg]';
            app.delta12v = uilabel(app.TopViewTab);
            app.delta12v.Text = '0 [deg]';
            app.SideViewTab = uitab(app.TabGroup);
            app.SideViewTab.Title = 'Side View';
            app.SideView = uiaxes(app.SideViewTab);
            title(app.SideView, 'Side View')
            xlabel(app.SideView, 'X')
            ylabel(app.SideView, 'Y')
            app.ScaleSliderLabel = uilabel(app.SideViewTab);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.SideViewTab);
            app.ScaleSlider.Limits = [1 10];
            app.ScaleSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.ScaleSlider.MinorTicks = [];
            app.ScaleSlider.Value = 1;
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTable = uitable(app.ResultsTab);
            app.ResultsTable.ColumnName = {'Load Case'; 'ΔF_f [N]'; 'ΔF_r [N]'; 'δZ_f [mm]'; 'δZ_r [mm]'; 'GC Front [mm]'; 'GC Rear [mm]'};
            app.ResultsTable.RowName = {''};
            app.FrontSuspensionTab = uitab(app.TabGroup);
            app.FrontSuspensionTab.Title = 'Front Suspension';
            app.FrontSusp = uiaxes(app.FrontSuspensionTab);
            title(app.FrontSusp, 'Front Suspension Characteristic')
            xlabel(app.FrontSusp, 'Deformation [mm]')
            ylabel(app.FrontSusp, 'Force [kN]')
            app.Kf = uilabel(app.FrontSuspensionTab);
            app.Kf.Text = 'Kf = ';
            app.Kfv = uilabel(app.FrontSuspensionTab);
            app.Kfv.Text = '0 [N/m]';
            app.RearSuspensionTab = uitab(app.TabGroup);
            app.RearSuspensionTab.Title = 'Rear Suspension';
            app.RearSusp = uiaxes(app.RearSuspensionTab);
            title(app.RearSusp, 'Rear Suspension Characteristics')
            xlabel(app.RearSusp, 'Deformation [mm]')
            ylabel(app.RearSusp, 'Force [kN]')
            app.Krv = uilabel(app.RearSuspensionTab);
            app.Krv.Text = '0 [N/m]';
            app.Krf = uilabel(app.RearSuspensionTab);
            app.Krf.Text = 'Kr = ';
            app.BodePlotTab = uitab(app.TabGroup);
            app.BodePlotTab.Title = 'Bode Plot';
            app.BMag = uiaxes(app.BodePlotTab);
            title(app.BMag, 'Bode Plot')
            xlabel(app.BMag, 'Frequency [Hz]')
            ylabel(app.BMag, 'Magnitude')
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.PSD = uiaxes(app.PSDTab);
            title(app.PSD, 'Power Spectral Density')
            xlabel(app.PSD, 'Frequency [Hz]')
            ylabel(app.PSD, 'PSD')
            app.MSVTab = uitab(app.TabGroup);
            app.MSVTab.Title = 'MSV';
            app.MSV = uiaxes(app.MSVTab);
            title(app.MSV, 'Mean Squared Value')
            xlabel(app.MSV, 'Damping Coefficient [Ns/m]')
            ylabel(app.MSV, 'mean squared value [m^2/s^4]')
            app.OptimizeButton = uibutton(app.MSVTab, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @Optimize, true);
            app.OptimizeButton.Enable = 'off';
            app.OptimizeButton.Text = 'Optimize';
            app.Lamp = uilamp(app.MSVTab);
            app.OptimalDampingCoefficientLabel = uilabel(app.MSVTab);
            app.OptimalDampingCoefficientLabel.Text = 'Optimal Damping Coefficient = ';
            app.ODC = uilabel(app.MSVTab);
            app.ODC.Text = '0';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @onResetClicked, true);
            app.ClearAllButton.Text = 'Clear All';
            app.RoadSuspensionPanel = uipanel(app.UIFigure);
            app.RoadSuspensionPanel.Title = 'Road & Suspension';
            app.BumpStopsLabel = uilabel(app.RoadSuspensionPanel);
            app.BumpStopsLabel.Text = 'Bump Stops';
            app.MaxCompressionmmEditFieldLabel = uilabel(app.RoadSuspensionPanel);
            app.MaxCompressionmmEditFieldLabel.Text = 'Max Compression [mm]';
            app.MaxCompressionmmEditField = uieditfield(app.RoadSuspensionPanel, 'numeric');
            app.MaxExpansionmmEditFieldLabel = uilabel(app.RoadSuspensionPanel);
            app.MaxExpansionmmEditFieldLabel.Text = 'Max Expansion [mm]';
            app.MaxExpansionmmEditField = uieditfield(app.RoadSuspensionPanel, 'numeric');
            app.LowerLimitmmEditFieldLabel = uilabel(app.RoadSuspensionPanel);
            app.LowerLimitmmEditFieldLabel.Text = 'Lower Limit [mm]';
            app.LowerLimitmmEditField = uieditfield(app.RoadSuspensionPanel, 'numeric');
            app.UpperLimitmmEditFieldLabel = uilabel(app.RoadSuspensionPanel);
            app.UpperLimitmmEditFieldLabel.Text = 'Upper Limit [mm]';
            app.UpperLimitmmEditField = uieditfield(app.RoadSuspensionPanel, 'numeric');
            app.NaturalFrequencyHzSliderLabel = uilabel(app.RoadSuspensionPanel);
            app.NaturalFrequencyHzSliderLabel.Text = 'Natural Frequency [Hz]';
            app.NaturalFrequencyHzSlider = uislider(app.RoadSuspensionPanel);
            app.NaturalFrequencyHzSlider.Limits = [1 1.5];
            app.NaturalFrequencyHzSlider.Value = 1.5;
            app.RoadGradeLabel = uilabel(app.RoadSuspensionPanel);
            app.RoadGradeLabel.Text = 'Road Grade';
            app.RoadGrade = uidropdown(app.RoadSuspensionPanel);
            app.RoadGrade.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
            app.RoadGrade.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.RoadGrade.Value = '0';
            app.VelocitykmhEditFieldLabel = uilabel(app.RoadSuspensionPanel);
            app.VelocitykmhEditFieldLabel.Text = 'Velocity [km/h]';
            app.VelocityEditField = uieditfield(app.RoadSuspensionPanel, 'numeric');
            app.ClearPlotsButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlots, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WheelArch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end